classdef dofapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResultTextArea            matlab.ui.control.TextArea
        ResultTextAreaLabel       matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        Initialx2mEditField       matlab.ui.control.NumericEditField
        Initialx2mEditFieldLabel  matlab.ui.control.Label
        Initialx1MEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        C2NsmEditField            matlab.ui.control.NumericEditField
        C2NsmEditFieldLabel       matlab.ui.control.Label
        C1NsmEditField            matlab.ui.control.NumericEditField
        C1NsmEditFieldLabel       matlab.ui.control.Label
        K2NmEditField             matlab.ui.control.NumericEditField
        K2NmEditFieldLabel        matlab.ui.control.Label
        K1NmEditField             matlab.ui.control.NumericEditField
        K1NmEditFieldLabel        matlab.ui.control.Label
        M2KgEditField             matlab.ui.control.NumericEditField
        M2KgEditFieldLabel        matlab.ui.control.Label
        M1KgEditField             matlab.ui.control.NumericEditField
        M1KgEditFieldLabel        matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes1                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           
    
    m1 = app.M1KgEditField.Value;
    m2 = app.M2KgEditField.Value;
    k1 = app.K1NmEditField.Value;
    k2 = app.K2NmEditField.Value;
    c1 = app.C1NsmEditField.Value;
    c2 = app.C2NsmEditField.Value;
    x1_0 = app.Initialx1MEditField.Value;
    x2_0 = app.Initialx2mEditField.Value;

    
    M = [m1, 0; 0, m2];
    K = [k1 + k2, -k2; -k2, k2];
    C = [c1 + c2, -c2; -c2, c2];

   
    A = [zeros(2), eye(2); -M\K, -M\C];
    y0 = [x1_0; x2_0; 0; 0];  
    tspan = 0:0.01:10;

   
    [t, y] = ode45(@(t, y) A*y, tspan, y0);

    
    plot(app.UIAxes1, t, y(:,1), 'r', t, y(:,2), 'b');
    title(app.UIAxes1, 'Displacement Response');
    xlabel(app.UIAxes1, 'Time (s)');
    ylabel(app.UIAxes1, 'Displacement (m)');
    legend(app.UIAxes1, 'DOF 1', 'DOF 2');

    
    plot(app.UIAxes2, t, y(:,3), 'r', t, y(:,4), 'b');
    title(app.UIAxes2, 'Velocity Response');
    xlabel(app.UIAxes2, 'Time (s)');
    ylabel(app.UIAxes2, 'Velocity (m/s)');
    legend(app.UIAxes2, 'DOF 1', 'DOF 2');

    
    wn = sqrt(eig(M\K));
    app.ResultTextArea.Value = {...
        sprintf('ω₁ = %.2f rad/s', wn(1)), ...
        sprintf('ω₂ = %.2f rad/s', wn(2))};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'UIAxes1')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'UIAxes2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.M1KgEditFieldLabel = uilabel(app.UIFigure);
            app.M1KgEditFieldLabel.Text = 'M1 (Kg)';
            app.M1KgEditField = uieditfield(app.UIFigure, 'numeric');
            app.M2KgEditFieldLabel = uilabel(app.UIFigure);
            app.M2KgEditFieldLabel.Text = 'M2 (Kg)';
            app.M2KgEditField = uieditfield(app.UIFigure, 'numeric');
            app.K1NmEditFieldLabel = uilabel(app.UIFigure);
            app.K1NmEditFieldLabel.Text = 'K1 (N/m)';
            app.K1NmEditField = uieditfield(app.UIFigure, 'numeric');
            app.K2NmEditFieldLabel = uilabel(app.UIFigure);
            app.K2NmEditFieldLabel.Text = 'K2 (N/m)';
            app.K2NmEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1NsmEditFieldLabel = uilabel(app.UIFigure);
            app.C1NsmEditFieldLabel.Text = 'C1 (Ns/m)';
            app.C1NsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2NsmEditFieldLabel = uilabel(app.UIFigure);
            app.C2NsmEditFieldLabel.Text = 'C2 (Ns/m)';
            app.C2NsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Initial x1(M)';
            app.Initialx1MEditField = uieditfield(app.UIFigure, 'numeric');
            app.Initialx2mEditFieldLabel = uilabel(app.UIFigure);
            app.Initialx2mEditFieldLabel.Text = 'Initial x2(m)';
            app.Initialx2mEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dofapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end